classdef SHARCQ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab_2                           matlab.ui.container.Tab
        CanbatchprocessanentirefolderLabel  matlab.ui.control.Label
        PreProcessImagesButton          matlab.ui.control.Button
        Tab_3                           matlab.ui.container.Tab
        CanbatchprocessanentirefolderLabel_2  matlab.ui.control.Label
        RegisterSectionstoAtlasButton   matlab.ui.control.Button
        Tab_4                           matlab.ui.container.Tab
        CanbatchprocessanentirefolderLabel_3  matlab.ui.control.Label
        RegisterROIButton               matlab.ui.control.Button
        Tab                             matlab.ui.container.Tab
        AnalyzessectionsindividuallyLabel  matlab.ui.control.Label
        AnalyzeSectionwithChonDataButton  matlab.ui.control.Button
        AnalyzeSectionwithAllenAtlasButton  matlab.ui.control.Button
        Tab_5                           matlab.ui.container.Tab
        WillplotallROIsthathavebeenanalyzedLabel  matlab.ui.control.Label
        PlotWireFrameBrainButton        matlab.ui.control.Button
        Tab_6                           matlab.ui.container.Tab
        withthecorrespondingatlasLabel  matlab.ui.control.Label
        WillplotsectionsthathavebeenanalyzedLabel  matlab.ui.control.Label
        GetregiondataforallanalyzedsectionsLabel  matlab.ui.control.Label
        ChonAtlasButton                 matlab.ui.control.Button
        AllenAtlasButton                matlab.ui.control.Button
        ConsiderationsbeforebeginningPanel  matlab.ui.container.Panel
        ImagesarealreadydownsampledCheckBox  matlab.ui.control.CheckBox
        ResolutionofimagesmicronspxEditField  matlab.ui.control.NumericEditField
        ResolutionofimagesmicronspxEditFieldLabel  matlab.ui.control.Label
        ViewingplaneDropDown            matlab.ui.control.DropDown
        ViewingplaneDropDownLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
        imageFolder = "";
        coordsFolder = "";
        annotationVolumeLocation = "";
        structureTreeLocation = "";
        templateVolumeLocation = "";
        CCFtoFP = "";
        FPtable = "";
        chonImages = "";
        suppLabels = "";
        roiLocation = "";
        roiAnnotation = "";
        individualSlices = true;
        imageResolution = 0.5;
        plane = 'coronal';
        downsampledAlready = false;
        text_file = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PreProcessImagesButton
        function Process_Histology(app, event)
            master = what(fileparts(mfilename('fullpath')));
            master_path = master.path;
            app.imageFolder = fullfile(master_path,'Images');
            app.coordsFolder = fullfile(master_path,'ROI Coordinates');
            app.text_file = ~isempty(dir(fullfile(app.coordsFolder,'*.txt')));
            if app.text_file
                n = length(dir(fullfile(app.coordsFolder,'*.txt')));
                for k = 1:n
                    importXY(app.coordsFolder,k);
                end
            end
            Process_Histology(app.imageFolder,app.coordsFolder,app.individualSlices,app.imageResolution,app.plane,app.downsampledAlready);
        end

        % Button pushed function: RegisterSectionstoAtlasButton
        function RegisterSectionstoAtlasButtonPushed(app, event)
            master = what(fileparts(mfilename('fullpath')));
            master_path = master.path;
            app.imageFolder = fullfile(master_path,'Images');
            app.annotationVolumeLocation = fullfile(master_path,'Atlas','Allen','annotation_volume_10um_by_index.npy');
            app.structureTreeLocation = fullfile(master_path,'Atlas','Allen','structure_tree_safe_2017.csv');
            app.templateVolumeLocation = fullfile(master_path,'Atlas','Allen','template_volume_10um.npy');
            Navigate_Atlas_and_Register_Slices(app.imageFolder,app.annotationVolumeLocation,app.structureTreeLocation,app.templateVolumeLocation,app.plane)
        end

        % Value changed function: ResolutionofimagesmicronspxEditField
        function ResolutionofimagesmicronspxEditFieldValueChanged(app, event)
            value = app.ResolutionofimagesmicronspxEditField.Value;
            app.imageResolution = value;
        end

        % Button pushed function: RegisterROIButton
        function RegisterROIButtonPushed(app, event)
            master = what(fileparts(mfilename('fullpath')));
            master_path = master.path;
            app.imageFolder = fullfile(master_path,'Images');
            app.annotationVolumeLocation = fullfile(master_path,'Atlas','Allen','annotation_volume_10um_by_index.npy');
            app.structureTreeLocation = fullfile(master_path,'Atlas','Allen','structure_tree_safe_2017.csv');
            app.templateVolumeLocation = fullfile(master_path,'Atlas','Allen','template_volume_10um.npy');
            Warp_ROI(app.imageFolder);
            fprintf('\n')
            fprintf('Image and ROI processing complete! \n')
        end

        % Button pushed function: AnalyzeSectionwithAllenAtlasButton
        function AnalyzeSectionwithAllenAtlasButtonPushed(app, event)
            master = what(fileparts(mfilename('fullpath')));
            master_path = master.path;
            app.imageFolder = fullfile(master_path,'Images');
            app.annotationVolumeLocation = fullfile(master_path,'Atlas','Allen','annotation_volume_10um_by_index.npy');
            app.structureTreeLocation = fullfile(master_path,'Atlas','Allen','structure_tree_safe_2017.csv');
            app.templateVolumeLocation = fullfile(master_path,'Atlas','Allen','template_volume_10um.npy');
            app.CCFtoFP = fullfile(master_path,'Atlas','Chon','CCF_to_FP.csv');
            app.FPtable = fullfile(master_path,'Atlas','Chon','FP_table_Chon_2020.csv');
            app.suppLabels = fullfile(master_path,'Atlas','Chon','Suppl_File1_Labels');
            app.chonImages = fullfile(master_path,'Atlas','Chon','Chon Images');
            fprintf('Select the original, unprocessed image file of the desired section to analyze: \n');
            file = uigetfile('*.tif','Select the original image of the desired section to analyze');
            [~, name, ~] = fileparts(file);
            [fig, app.roiLocation, app.roiAnnotation] = Analyze_ROIs(app.imageFolder,name,app.annotationVolumeLocation,app.structureTreeLocation,app.plane,true,app.chonImages,app.CCFtoFP);
            Allen_Data_Folder = fullfile(app.imageFolder,'Allen Data');
            if ~exist(Allen_Data_Folder)
                mkdir(Allen_Data_Folder)
            end
            exportgraphics(fig,fullfile(Allen_Data_Folder,append(name,'_Allen_Figure','.tif')),"BackgroundColor",'white');
            writecell(app.roiAnnotation(:,2),fullfile(Allen_Data_Folder,append(name,'_Allen_Data','.txt')));
        end

        % Button pushed function: AnalyzeSectionwithChonDataButton
        function AnalyzeSectionwithChonDataButtonPushed(app, event)
            master = what(fileparts(mfilename('fullpath')));
            master_path = master.path;
            app.imageFolder = fullfile(master_path,'Images');
            app.annotationVolumeLocation = fullfile(master_path,'Atlas','Allen','annotation_volume_10um_by_index.npy');
            app.structureTreeLocation = fullfile(master_path,'Atlas','Allen','structure_tree_safe_2017.csv');
            app.templateVolumeLocation = fullfile(master_path,'Atlas','Allen','template_volume_10um.npy');
            app.CCFtoFP = fullfile(master_path,'Atlas','Chon','CCF_to_FP.csv');
            app.FPtable = fullfile(master_path,'Atlas','Chon','FP_table_Chon_2020.csv');
            app.suppLabels = fullfile(master_path,'Atlas','Chon','Suppl_File1_Labels');
            app.chonImages = fullfile(master_path,'Atlas','Chon','Chon Images');
            fprintf('Select the original, unprocessed image file of the correct section to analyze: \n');
            file = uigetfile('*.tif','Select the original image of the desired section to analyze');
            [~, name, ~] = fileparts(file);
            [fig, app.roiLocation, app.roiAnnotation] = Analyze_ROIs(app.imageFolder,name,app.annotationVolumeLocation,app.structureTreeLocation,app.plane,false,app.chonImages,app.CCFtoFP);
            Chon_Data_Folder = fullfile(app.imageFolder,'Chon Data');
            if ~exist(Chon_Data_Folder)
                mkdir(Chon_Data_Folder)
            end
            [dataTable] = Convert_CCF_Coords_to_FP_Regions(app.imageFolder,app.roiLocation,app.annotationVolumeLocation,app.structureTreeLocation,app.CCFtoFP,app.FPtable,app.chonImages,app.suppLabels,app.plane);
            if app.plane == "coronal"
                exportgraphics(fig,fullfile(Chon_Data_Folder,append(name,'_Chon_Figure','.tif')),'BackgroundColor','white');
            end
            writetable(dataTable(:,3),fullfile(Chon_Data_Folder,append(name,'_Chon_Data','.txt')));
        end

        % Button pushed function: PlotWireFrameBrainButton
        function PlotWireFrameBrainButtonPushed(app, event)
            master = what(fileparts(mfilename('fullpath')));
            master_path = master.path;
            app.imageFolder = fullfile(master_path,'Images');
            Plot_Wire_Frame_Brain(app.imageFolder);
        end

        % Button pushed function: ChonAtlasButton
        function ChonAtlasButtonPushed(app, event)
            master = what(fileparts(mfilename('fullpath')));
            master_path = master.path;
            app.imageFolder = fullfile(master_path,'Images');
            Count_Cell_Locations(app.imageFolder,'chon');
        end

        % Button pushed function: AllenAtlasButton
        function AllenAtlasButtonPushed(app, event)
            master = what(fileparts(mfilename('fullpath')));
            master_path = master.path;
            app.imageFolder = fullfile(master_path,'Images');
            Count_Cell_Locations(app.imageFolder,'allen');
        end

        % Value changed function: ImagesarealreadydownsampledCheckBox
        function ImagesarealreadydownsampledCheckBoxValueChanged(app, event)
            value = app.ImagesarealreadydownsampledCheckBox.Value;
            if value == true
                app.downsampledAlready = true;
            else
                app.downsampledAlready = false;
            end
        end

        % Value changed function: ViewingplaneDropDown
        function ViewingplaneDropDownValueChanged(app, event)
            value = app.ViewingplaneDropDown.Value;
            app.plane = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ConsiderationsbeforebeginningPanel = uipanel(app.UIFigure);
            app.ConsiderationsbeforebeginningPanel.Title = 'Considerations before beginning:';
            app.ViewingplaneDropDownLabel = uilabel(app.ConsiderationsbeforebeginningPanel);
            app.ViewingplaneDropDownLabel.Text = 'Viewing plane:';
            app.ViewingplaneDropDown = uidropdown(app.ConsiderationsbeforebeginningPanel);
            app.ViewingplaneDropDown.Items = {'coronal', 'sagittal', 'transverse'};
            app.ViewingplaneDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewingplaneDropDownValueChanged, true);
            app.ViewingplaneDropDown.Value = 'coronal';
            app.ResolutionofimagesmicronspxEditFieldLabel = uilabel(app.ConsiderationsbeforebeginningPanel);
            app.ResolutionofimagesmicronspxEditFieldLabel.Text = 'Resolution of images (microns/px)';
            app.ResolutionofimagesmicronspxEditField = uieditfield(app.ConsiderationsbeforebeginningPanel, 'numeric');
            app.ResolutionofimagesmicronspxEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionofimagesmicronspxEditFieldValueChanged, true);
            app.ResolutionofimagesmicronspxEditField.Value = 0.5;
            app.ImagesarealreadydownsampledCheckBox = uicheckbox(app.ConsiderationsbeforebeginningPanel);
            app.ImagesarealreadydownsampledCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImagesarealreadydownsampledCheckBoxValueChanged, true);
            app.ImagesarealreadydownsampledCheckBox.Text = 'Images are already downsampled';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '1';
            app.PreProcessImagesButton = uibutton(app.Tab_2, 'push');
            app.PreProcessImagesButton.ButtonPushedFcn = createCallbackFcn(app, @Process_Histology, true);
            app.PreProcessImagesButton.Text = 'Pre-Process Images';
            app.CanbatchprocessanentirefolderLabel = uilabel(app.Tab_2);
            app.CanbatchprocessanentirefolderLabel.Text = '*Can batch process an entire folder ';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '2';
            app.RegisterSectionstoAtlasButton = uibutton(app.Tab_3, 'push');
            app.RegisterSectionstoAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterSectionstoAtlasButtonPushed, true);
            app.RegisterSectionstoAtlasButton.Text = 'Register Sections to Atlas';
            app.CanbatchprocessanentirefolderLabel_2 = uilabel(app.Tab_3);
            app.CanbatchprocessanentirefolderLabel_2.Text = '*Can batch process an entire folder ';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '3';
            app.RegisterROIButton = uibutton(app.Tab_4, 'push');
            app.RegisterROIButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterROIButtonPushed, true);
            app.RegisterROIButton.Text = 'Register ROI ';
            app.CanbatchprocessanentirefolderLabel_3 = uilabel(app.Tab_4);
            app.CanbatchprocessanentirefolderLabel_3.Text = '*Can batch process an entire folder ';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '4';
            app.AnalyzeSectionwithAllenAtlasButton = uibutton(app.Tab, 'push');
            app.AnalyzeSectionwithAllenAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeSectionwithAllenAtlasButtonPushed, true);
            app.AnalyzeSectionwithAllenAtlasButton.Text = 'Analyze Section with Allen Atlas';
            app.AnalyzeSectionwithChonDataButton = uibutton(app.Tab, 'push');
            app.AnalyzeSectionwithChonDataButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeSectionwithChonDataButtonPushed, true);
            app.AnalyzeSectionwithChonDataButton.Text = 'Analyze Section with Chon Data';
            app.AnalyzessectionsindividuallyLabel = uilabel(app.Tab);
            app.AnalyzessectionsindividuallyLabel.Text = '*Analyzes sections individually';
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = '5';
            app.PlotWireFrameBrainButton = uibutton(app.Tab_5, 'push');
            app.PlotWireFrameBrainButton.ButtonPushedFcn = createCallbackFcn(app, @PlotWireFrameBrainButtonPushed, true);
            app.PlotWireFrameBrainButton.Text = 'Plot Wire Frame Brain';
            app.WillplotallROIsthathavebeenanalyzedLabel = uilabel(app.Tab_5);
            app.WillplotallROIsthathavebeenanalyzedLabel.Text = '*Will plot all ROIs that have been analyzed';
            app.Tab_6 = uitab(app.TabGroup);
            app.Tab_6.Title = '6';
            app.AllenAtlasButton = uibutton(app.Tab_6, 'push');
            app.AllenAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @AllenAtlasButtonPushed, true);
            app.AllenAtlasButton.Text = 'Allen Atlas';
            app.ChonAtlasButton = uibutton(app.Tab_6, 'push');
            app.ChonAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @ChonAtlasButtonPushed, true);
            app.ChonAtlasButton.Text = 'Chon Atlas';
            app.GetregiondataforallanalyzedsectionsLabel = uilabel(app.Tab_6);
            app.GetregiondataforallanalyzedsectionsLabel.Text = 'Get region data for all analyzed sections:';
            app.WillplotsectionsthathavebeenanalyzedLabel = uilabel(app.Tab_6);
            app.WillplotsectionsthathavebeenanalyzedLabel.Text = '*Will plot sections that have been analyzed';
            app.withthecorrespondingatlasLabel = uilabel(app.Tab_6);
            app.withthecorrespondingatlasLabel.Text = 'with the corresponding atlas';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SHARCQ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end